classdef MARK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        text10                          matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        AppendNameEditField             matlab.ui.control.EditField
        AppendNameEditFieldLabel        matlab.ui.control.Label
        pushbutton6                     matlab.ui.control.Button
        uipanel3_2                      matlab.ui.container.Panel
        ofCurvesEditField_2             matlab.ui.control.NumericEditField
        ofCurvesEditFieldLabel_2        matlab.ui.control.Label
        edit11_3                        matlab.ui.control.EditField
        text15_5                        matlab.ui.control.Label
        edit12_2                        matlab.ui.control.EditField
        edit11_2                        matlab.ui.control.EditField
        text16_4                        matlab.ui.control.Label
        text15_4                        matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        Kd_textbox                      matlab.ui.control.EditField
        text22_2                        matlab.ui.control.Label
        edit17                          matlab.ui.control.EditField
        text22                          matlab.ui.control.Label
        edit12                          matlab.ui.control.EditField
        edit11                          matlab.ui.control.EditField
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        CalculationsPanel               matlab.ui.container.Panel
        SLBSurfaceAreamm2EditField      matlab.ui.control.NumericEditField
        SLBSurfaceAreamm2EditFieldLabel  matlab.ui.control.Label
        VuLofAEditField                 matlab.ui.control.NumericEditField
        VuLofAEditFieldLabel            matlab.ui.control.Label
        BpmolEditField                  matlab.ui.control.NumericEditField
        BpmolEditFieldLabel             matlab.ui.control.Label
        BmolEditField                   matlab.ui.control.NumericEditField
        BmolEditFieldLabel              matlab.ui.control.Label
        ApmolEditField                  matlab.ui.control.NumericEditField
        ApmolEditFieldLabel             matlab.ui.control.Label
        AnMproteinEditField             matlab.ui.control.NumericEditField
        AnMproteinEditFieldLabel        matlab.ui.control.Label
        YCalFactorpmolTIRFEditField     matlab.ui.control.NumericEditField
        YCalFactorpmolTIRFEditFieldLabel  matlab.ui.control.Label
        TIRFFCSSlopeTIRFum2EditField    matlab.ui.control.NumericEditField
        TIRFFCSSlopeTIRFum2EditFieldLabel  matlab.ui.control.Label
        pushbutton4                     matlab.ui.control.Button
        pushbutton1                     matlab.ui.control.Button
        ExtraSimulationParametersPanel  matlab.ui.container.Panel
        TimeIntervalEditField           matlab.ui.control.NumericEditField
        TimeIntervalEditFieldLabel      matlab.ui.control.Label
        ofCurvesEditField               matlab.ui.control.NumericEditField
        ofCurvesEditFieldLabel          matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        edit13                          matlab.ui.control.EditField
        text18                          matlab.ui.control.Label
        edit19                          matlab.ui.control.EditField
        edit18                          matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        edit10                          matlab.ui.control.EditField
        text2                           matlab.ui.control.Label
        edit1                           matlab.ui.control.EditField
        uipanel7                        matlab.ui.container.Panel
        edit15                          matlab.ui.control.EditField
        edit16                          matlab.ui.control.EditField
        text21                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        pushbutton2                     matlab.ui.control.Button
        Switch                          matlab.ui.control.ToggleSwitch
        text9_2                         matlab.ui.control.Label
        popupmenu1                      matlab.ui.control.DropDown
        text24                          matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        edit4                           matlab.ui.control.EditField
        checkbox2                       matlab.ui.control.CheckBox
        checkbox1                       matlab.ui.control.CheckBox
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        edit9                           matlab.ui.control.EditField
        edit8                           matlab.ui.control.EditField
        text6                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        edit3                           matlab.ui.control.EditField
        edit2                           matlab.ui.control.EditField
        DataVisualizationPanel          matlab.ui.container.Panel
        axes1                           matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = public)
        fit_simulate = 1; % holds 1 if 'Fit' is toggled, 'Simulate' if Simulate is toggled, always starts at 1
        final_simulate_conc; % 
        simulation_complete = 0; % 
        mechanism; % 
        final_reag_conc; % 
        data_best_fit; % 
        all_reag_conc; % 
        output; % holds the table that is output as an excel csv file
        ODE_sim; % 
        t_sim; % 
        k_d; %
        legend_labels; %
        symbols; %
        tsim; %
        line_colors; %
    end

    methods (Access = private)

        function dydt = bimolecular_eqn(app, t, y, k, fx, k1, k2)
            % defines mass action rate laws for bimolecular mechanism
            % fx selects for fixed rate constants

            A = y(1);
            B = y(2);
            P = y(3);

            if fx == 0
                dydt(1) = -k(1)*A*B + k(2)*P;
                dydt(2) = -k(1)*A*B + k(2)*P;
                dydt(3) = k(1)*A*B - k(2)*P;
            elseif fx == 1
                dydt(1) = -k1*A*B + k(2)*P;
                dydt(2) = -k1*A*B + k(2)*P;
                dydt(3) = k1*A*B - k(2)*P;
            elseif fx == 2
                dydt(1) = -k(1)*A*B + k2*P;
                dydt(2) = -k(1)*A*B + k2*P;
                dydt(3) = k(1)*A*B - k2*P;
            end

            dydt = dydt';
        end

        function cost = chisquare(app, y_real, k_guess, t_span, y0, t_int, fxn, k1, k2, mech, data)

            % calculates the chi square of the
            % fit data, that is the square of the difference between the real and fit
            % data
            % this function is minimized using fminsearchbnd.m to optimize the fit

            % aside from the chi_sq_f and chi_sq_hold lines and the final for block,
            % everything else in this function is identical to the simulation function
            % gets size of y-axis data to make a matrix to hold the fit data
            Row_size = size(data,2) - 1;
            Column_size = size(data,1);

            % chi_sq_f holds the final chi square (square of real - fit difference)
            chi_sq_f = 0;
            chi_sq_hold = 0;

            % constructs matrix to hold the fit data of the time course for the dataset
            y_fit = zeros([Row_size Column_size]);

            if mech == 1
                for Row_ind = 1:(Row_size)
                    % Row_ind picks a column of y0 values to start fitting with
                    y_start = y0(:, Row_ind);
                    % ode45 solves the differential equation for the mass action rate
                    % law
                    ODE_sol_struct = ode45(@(t,y)unimolecular_eqn(app, t,y,k_guess, fxn, k1, k2), t_span, y_start);
                    % deval
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    % takes ode solution out of struct as a column
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    % adds ode solution to y_fit matrix, transposing it to a row
                    y_fit(Row_ind, :) = ODE_sol_temp;
                    

                end
            elseif mech == 2
                for Row_ind = 1:(Row_size)
                    % same as the above block, but for bimolecular mechanisms
                    y_start = y0(:, Row_ind);
                    ODE_sol_struct = ode45(@(t,y)bimolecular_eqn(app, t,y,k_guess, fxn, k1, k2), t_span, y_start);
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    y_fit(Row_ind, :) = ODE_sol_temp;

                end
            end

            % this block does the chisquare calculation
            % the two for blocks calculate the chi_square for each data point in all the
            % time courses

            for Column_ind = 1:(Column_size)

                for Row_ind = 1:(Row_size)
                    % chi_sq_hold is used to calculate the chi square for any certain
                    % point
                    chi_sq_hold = (y_real(Row_ind, Column_ind) - y_fit(Row_ind, Column_ind))^2;
                    % chi_sq_f is summed using its previous value and the newly
                    % calculated chi_sq_hold
                    chi_sq_f = chi_sq_f + chi_sq_hold;

                end

            end
            % outputs the chi square sum as cost
            cost = chi_sq_f;
        end

        function pushbutton5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton 5 is the former 'Notes' button, omitted as of 2022-7-7

            h = msgbox({'This GUI fits time course data to selected mass action kinetic model'...
                'using numeric ordinary differential equation solver.'...
                'Applications include membrane adsorption experiment by TIRF.'...
                'Units can be specified using textboxes next to calibration factors.'...
                'Written by Jean Chung, February 2017; Cyrus Picou, Jr. August 2022'...
                '' ...
                'Data file must be in the following format:'...
                '    [time data data data data]' ...
                ''...
                'Copy result button will copy the following to the clipboard:'...
                '    [fit fit fit fit]'...
                },'Notes');
        end

        function regression = regr(app, y_real, y_fit, mech, fx)
            % calculates residual standard error = sqrt((yi-yfiti)^2/df)

            Row_size = size(y_real,2); % measures length of the rows in y_axis data
            Column_size = size(y_real,1); % measures length of the columns in y_axis data


            % construct a matrix to save the residuals for each data point in each trace
            residuals = zeros([Row_size, Column_size]);
            % placeholder for the sum of the residuals
            residuals_sum = 0;

            % degrees of freedom 'df' is the difference of the number of datapoints 'n'
            % and the fitted parameters "such as kf and kr"
            % fx variable denotes when one parameter is fixed, df = n - 1
            % if blocks for mechanisms 1 & 2, unimolecular and bimolecular, do not
            % change calculation of df, but added in case a future mechanism with a
            % different number of fitting parameters is added


            if fx == 0
                df = Row_size*Column_size - 2;
            else
                df = Row_size*Column_size - 1;
            end
            
            % two for blocks iterate calculating the residuals for each data point
            % sums up total residuals into residual sums

            for x_ind = 1:(Column_size)


                for y_ind = 1:(Row_size)
                    residuals(x_ind, y_ind) = (y_real(x_ind, y_ind) - y_fit(x_ind, y_ind))^2;

                    residuals_sum = residuals_sum + residuals(x_ind, y_ind);

                end
            end

            % RSE is residual standard error, finishes calculation and outputs RSE
            RSE = sqrt(residuals_sum/df);

            regression = RSE;
        end

        function [y_simulation, y_fit_concentration] = sim(app, k_guess, t_span, y0, t_int, fxn, k1, k2, mech, data)
            % simulation function takes in time (x-axis) data and simulates the time
            % course data dP/dt
            % this function is used to calculate the initial fit line

            % gets size of y-axis data to make a matrix to hold the fit data
            Row_size = size(data, 2) - 1;
            Column_size = size(data, 1);


            % creates a matrix to hold the fit data
            y_fit = zeros([Row_size Column_size]);
            %y_fit_conc = [];

            % if loops pick between unimolecular (mech = 1) and bimolecular (mech = 2)

            if mech == 1
                y_fit_conc = zeros([2, Column_size, Row_size]);
                for Row_ind = 1:(Row_size)
                    % Row_ind picks a column of y0 values to start fitting with
                    y_start = y0(:, Row_ind);
                    % ode45 solves the differential equation for the mass action rate
                    % law
                    ODE_sol_struct = ode45(@(t,y)unimolecular_eqn(app, t,y,k_guess, fxn, k1, k2), t_span, y_start);
                    % deval
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    % takes ode solution out of struct as a column
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    % adds ode solution to y_fit matrix, transposing it to a row
                    y_fit(Row_ind, :) = ODE_sol_temp;
                    for i = 1:2
                        y_fit_conc(i, :, Row_ind) = ODE_deval_holder(i,:);
                    end

                end
            elseif mech == 2
                y_fit_conc = zeros([3, Column_size, Row_size]);
                for Row_ind = 1:(Row_size)
                    % same for above but with bimolecular equation
                    y_start = y0(:, Row_ind);
                    ODE_sol_struct = ode45(@(t,y)bimolecular_eqn(app, t,y,k_guess, fxn, k1, k2), t_span, y_start);
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    y_fit(Row_ind, :) = ODE_sol_temp;
                    for i = 1:3
                        y_fit_conc(i, :, Row_ind) = ODE_deval_holder(i,:);
                    end

                end
            end

            % outputs y_fit matrix as y_simulation function
            y_simulation = y_fit;
            y_fit_concentration = y_fit_conc;
        end

        function [y_simulate_kinetics, y_final_conc, y_fit_concentration] = simulate_kinetics(app, k_guess, t_span, y0, t_int, fxn, k1, k2, mech,size)
            % similar to "sim" function, but modified for
            % "Simulate" function

            y_fit = [];
            y_final = [];
            y_fit_conc = [];

            if mech == 1
                for Row_ind = 1:(size)
                    y_start = y0(:,Row_ind);
                    ODE_sol_struct = ode45(@(t,y)unimolecular_eqn(app,t,y,k_guess,fxn,k1,k2), t_span, y_start);
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    y_fit(Row_ind, :) = ODE_sol_temp;
                    y_final(Row_ind,:) = deval(ODE_sol_struct, t_span(2));
                    for i = 1:2
                        y_fit_conc(i, :, Row_ind) = ODE_deval_holder(i,:);
                    end
                end
            elseif mech == 2
                for Row_ind = 1:(size)

                    y_start = y0(:, Row_ind);
                    ODE_sol_struct = ode45(@(t,y)bimolecular_eqn(app, t, y, k_guess,fxn,k1,k2), t_span, y_start);
                    ODE_deval_holder = deval(ODE_sol_struct, t_int);
                    ODE_sol_temp = ODE_deval_holder(end,:);
                    y_fit(Row_ind, :) = ODE_sol_temp;
                    y_final(Row_ind,:) = deval(ODE_sol_struct, t_span(2));
                    for i = 1:3
                        y_fit_conc(i, :, Row_ind) = ODE_deval_holder(i,:);
                    end
                end
            end
            y_simulate_kinetics = y_fit;
            y_final_conc = y_final;
            y_fit_concentration = y_fit_conc;


        end

        function dydt = unimolecular_eqn(app, t, y, k, fx, k1, k2)
            % defines mass action rate laws for unimolecular mechanism
            % fx variable selects for fixed rate constants

            A = y(1);
            P = y(2);

            if fx == 0
                dydt(1) = -k(1)*A + k(2)*P;
                dydt(2) = k(1)*A - k(2)*P;
            elseif fx == 1
                dydt(1) = -k1*A + k(2)*P;
                dydt(2) = k1*A - k(2)*P;
            elseif fx == 2
                dydt(1) = -k(1)*A + k2*P;
                dydt(2) = k(1)*A - k2*P;
            end

            dydt = dydt';
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to main (see VARARGIN)

            % Choose default command line output for main
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            movegui(app.figure1, 'center');

            % UIWAIT makes main wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            % checkbox1 is checkbox to the right of "kf" textbox, fixes kf to its
            % textbox value
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of checkbox2
            % checkbox2 is checkbox to the right of "kr" textbox, fixes kr to its textbox value
        end

        % Value changed function: edit10
        function edit10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit10 as text
            %        str2double(get(hObject,'String')) returns contents of edit10 as a double
            % edit10 is textbox next to 'y:', its value will be multiplied to all the
            % y-axis data to change its units, i.e. TIRF intensity -> picomoles
        end

        % Value changed function: edit11
        function edit11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit11 as text
            %        str2double(get(hObject,'String')) returns contents of edit11 as a double
            % edit11 is textbox next to 'kf', outputs fitted kf
        end

        % Value changed function: edit12
        function edit12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit12 as text
            %        str2double(get(hObject,'String')) returns contents of edit12 as a double
            % edit12 is textbox next to kr, outputs kr from fitting
        end

        % Value changed function: edit13
        function edit13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit13 as text
            %        str2double(get(hObject,'String')) returns contents of edit13 as a double
            % edit13 is textbox next to "Exclude Column #', it will remove a data column
            % of loaded data from the GUI's graph and will not include in fitting
        end

        % Value changed function: edit15
        function edit15_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit15 as text
            %        str2double(get(hObject,'String')) returns contents of edit15 as a double
            % edit15 is textbox next to "low", defines lower boundary to fit kf and kr
        end

        % Value changed function: edit16
        function edit16_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit16 as text
            %        str2double(get(hObject,'String')) returns contents of edit16 as a double
            % edit16 is textbox next to "up", defines upper boundary to fit kf and kr
        end

        % Value changed function: edit17
        function edit17_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit17 as text
            %        str2double(get(hObject,'String')) returns contents of edit17 as a double
            % edit17 is textbox to the right of 'error' which will output
            % the standard error of regression
        end

        % Value changed function: edit18
        function edit18_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit18 as text
            %        str2double(get(hObject,'String')) returns contents of edit18 as a double
            % edit18 is textbox next to x calibration factor textbox
        end

        % Value changed function: edit19
        function edit19_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit19 as text
            %        str2double(get(hObject,'String')) returns contents of edit19 as a double
            % edit19 is textbox next to y calibration factor textbox
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double

            %edit1 is calibration factor x textbox, changes x-axis units by
            %multiplication, i.e. from minutes -> seconds
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            % edit2 is "A0" textbox under "Optimization conditions"
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            % edit3 is "B0" textbox under "Optimization conditions"
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit4 as text
            %        str2double(get(hObject,'String')) returns contents of edit4 as a double
            % edit4 is "P0" textbox under "Optimization conditions"
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit8 as text
            %        str2double(get(hObject,'String')) returns contents of edit8 as a double
            % edit8 is "kf" textbox under "Optimization conditions"
        end

        % Value changed function: edit9
        function edit9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit9 as text
            %        str2double(get(hObject,'String')) returns contents of edit9 as a double
            % edit9 is "kr" textbox under "Optimization conditions"
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            % popupmenu1 is the popupmenu that allows selection of unimolecular or
            % bimolecular reaction mechanisms
            str = get(hObject,'String');
            val = get(hObject,'Value');
            switch str{val};
                case 'Unimolecular'
                    set(handles.text9,'String','A <--> P');
                case 'Bimolecular'
                    set(handles.text9,'String','A + B <--> P');
            end
            guidata(gcbo,handles)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton1 is "Load data" button
            % calls script uigetfile2 to select data file to load into program
            [filename, pathname] = uigetfile2('*.*', 'select file');

            if filename == 0
                % ignore error if no file is selected by pressing "Load
                % data" button
                return
            
            end


            % loads data from data file
            file = fullfile(pathname,filename);
            data = load(file);
            % edit13 is textbox to the right of "Data column", specifies columns which
            % contains kinetic y-axis data, such as TIRF intensity
            % data_exclude specifies data column to be omitted from fitting, if =0 does nothing
            data_exclude = str2num(get(handles.edit13, 'String'));

            if data_exclude ~= 0
                data(:,data_exclude) = [];
            else

            end

            data_length = size(data,2); % final column index which contains binding data

            % loads time axis data, always assumed to be in first column
            t_exp = data(:,1);
            y_exp = data(:,2:data_length);

            % calib_y and calib_x are textboxes under Calibration factor next to y and
            % x, respectively. Calibrates kinetic data from something like TIRF
            % intensity to units such as picomoles

            calib_y = str2num(get(handles.edit10, 'String'));
            calib_x = str2num(get(handles.edit1, 'String'));
            y_exp = calib_y*y_exp;
            t_exp = calib_x*t_exp;
            % clears axes (graph area)
            cla

            app.legend_labels = cell(1, ((data_length - 1)*2));

            for i_1 = 1:(data_length - 1)
                app.legend_labels{i_1} = num2str(i_1);
                app.legend_labels{i_1 + data_length - 1} = append('fit ',num2str(i_1));
            end

            % gets units for axis titles
            x_axis = get(handles.edit18, 'String');
            y_axis = get(handles.edit19, 'String');

            app.symbols = {'k*','ko', 'k+', 'kx', 'ks', 'kd', 'k^', 'kv', 'k>', 'k<', 'kp', 'kh'};

            % plots loaded raw data

            hold on;

            for i = 1:(data_length - 1)
                if i > size(app.symbols)
                    plot(handles.axes1,t_exp,y_exp(:,i),app.symbols{1})
                else
                    plot(handles.axes1,t_exp,y_exp(:,i),app.symbols{i})
                end

                %symbol = symbols{i};
                %plot(handles.axes1,t_exp,y_exp(:,i),app.symbols{i})
            end

            hold off;


            %plot(handles.axes1,t_exp,y_exp,symbols)
            set(gca,'FontSize',12)
            xlabel(x_axis,'FontSize',14,'FontWeight','bold');
            ylabel(y_axis,'FontSize',14,'FontWeight','bold');
            legend(handles.axes1,app.legend_labels(1,1:(data_length - 1)),'Location','northwest');

            % saves data to handles so they can be called by other functions
            handles.t_exp = t_exp;
            handles.y_exp = y_exp;
            handles.data = data;
            handles.data_length = data_length;
            handles.x_axis = x_axis;
            handles.y_axis = y_axis;
            handles.x_calib = calib_x;
            handles.y_calib = calib_y;
            handles.data_exclude = data_exclude;

            % saves filename and pathname to handles so they can be used to export file
            handles.pathname = pathname;
            handles.filename = filename;
            handles.file = file;
            % gcbo returns the handle of the object whose callback is currently
            % executing and the figure containing that object
            % handles contains data that should be used in other functions in the
            % program
            guidata(gcbo,handles)
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton2 is "Fit/Sim" pushbutton 
            % Button either fits or simulates TIRF intensity data for
            % bilayer binding experiments

            % Select reaction mechanism
            str = get(handles.popupmenu1, 'String');
            val = get(handles.popupmenu1,'Value');
            switch str{val};
                case 'Unimolecular'
                    mech = 1;
                case 'Bimolecular'
                    mech = 2;
            end

            app.mechanism = mech;
            % If either k1 and k2 are fixed, change fit functions.  If both are selected, an error message
            % appears and the execution is halted.

            fix_k1 = get(handles.checkbox1,'Value');
            fix_k2 = get(handles.checkbox2,'Value');
            
            if mech == 1
                if fix_k1 == 1 && fix_k2 == 1
                    h = msgbox('Pick just one paramter to fix!', 'Error','error');
                    error('Cannot fix both parameters');
                elseif fix_k1 == 1 && fix_k2 == 0
                    fx = 1;
                elseif fix_k1 == 0 && fix_k2 == 1
                    fx = 2;
                else
                    fx = 0;
                end
            elseif mech == 2
                if fix_k1 == 1 && fix_k2 == 1
                    h = msgbox('Pick just one paramter to fix!', 'Error','error');
                    error('Cannot fix both parameters');
                elseif fix_k1 == 1 && fix_k2 == 0
                    fx = 1;
                elseif fix_k1 == 0 && fix_k2 == 1
                    fx = 2;
                else
                    fx = 0;
                end

            end

            % Set up the parameters, starting concentrations and initial rate constant
            % guesses

            if app.fit_simulate == 1
                size1 = size(handles.data, 2) - 1;
            elseif app.fit_simulate == 2
                size1 = app.ofCurvesEditField.Value;
            end
            %size1 = size(handles.data, 2) - 1;
            if mech == 1
                y0 = zeros([2 size1]);
                y0(1,:) = str2num(get(handles.edit2, 'String')); % A0

                y0(2,:) = str2num(get(handles.edit4, 'String')); % P0
            elseif mech == 2
                y0 = zeros([3 size1]);
                y0(1,:) = str2num(get(handles.edit2, 'String')); % A0

                y0(2,:) = str2num(get(handles.edit3, 'String')); % B0
                y0(3,:) = str2num(get(handles.edit4, 'String')); % P0
            end

            % take initial rate constants from textboxes
            k0 = [];
            k0(1) = str2num(get(handles.edit8, 'String')); % kf
            k0(2) = str2num(get(handles.edit9, 'String')); % kr

            k1 = k0(1);
            k2 = k0(2);

            % run "Fit" or "Simulate" modes in MARK
            if app.fit_simulate == 1
                % if "Fit" is selected, runs this block to fit binding data

                % get x and y data from handles to use for fitting
                y_exp = handles.y_exp';
                t_exp = handles.t_exp';
                t_span = [0 t_exp(end)];
                data = handles.data;

                % calls sim function to use ODE solver to fit kinetic curve to mass action
                % rate law dydt

                % If block to select for fitting curve or simulate curves
                % costf is a measure of the difference between the fit and the raw data,
                % fminsearchbnd minimized costf by changing the kinetic rate constants in
                % k0, saving the results as bestk
                costf = @(x)chisquare(app, y_exp,x,t_span,y0,t_exp,fx,k1,k2, mech, data);

                % Take bounds given by the user for fminsearchbnd
                % edit15 is textbox by "low", which is lb, edit16 is textbox by "up", ub
                low = str2num(get(handles.edit15, 'String')); % lb - fit k will be >= this #
                up = str2num(get(handles.edit16, 'String')); % ub - fit k will be <= this #

                %assign bounds based on chosen reaction mechanism

                if mech == 1
                    lb = [low low]; % define lower bounds for fminsearchbnd
                    ub = [up up]; % define upper bounds for fminsearchbnd
                elseif mech == 2
                    lb = [low low]; % defines lower bounds for fminsearchbnd
                    ub = [up up]; % defines upper bounds for fminsearchbnd
                end
                %lb = [low low]; % define lower bounds for fminsearchbound
                %ub = [up up]; % define upper bounds for fminsearchbound
                options = optimset('MaxFunEvals', 1000); % sets options for fminsearchbnd
                [bestk,~,~,~] = fminsearchbnd(costf,k0,lb,ub,options); % runs fminsearch bound to optimize fit paramters

                % ODE_best_fit solves the ODE for the mass action rate law for the kinetic
                % constants bestk
                % ODE_best_conc is the concentration of each species over
                % time corresponding to the solved kinetic constants
                [ODE_best_fit, ODE_best_conc] = sim(app, bestk, t_span, y0, t_exp, fx, k1, k2, mech,data);
                % clears axes (plot)
                cla

                % gets units for axis titles
                x_axis = handles.x_axis;
                y_axis = handles.y_axis;

                % sets up plot of raw data with fit lines

                app.line_colors = {'r--','g--','b--','m--','y--','k--'};


                hold on;

                for i = 1:(handles.data_length - 1)
                    if i > size(app.symbols)
                        plot(handles.axes1,t_exp,handles.y_exp(:,i),app.symbols{1})
                    else
                        plot(handles.axes1,handles.t_exp,handles.y_exp(:,i),app.symbols{i})
                    end
                end

                %legend(handles.axes1,app.legend_labels(1,:),'Location','northwest');

                %plot(handles.axes1,t_exp,ODE_best_fit,'r--');
                for i = 1:(handles.data_length - 1)
                    if i > size(app.line_colors)
                        plot(handles.axes1,t_exp,ODE_best_fit(i,:),app.line_colors{1})
                    else
                        plot(handles.axes1,t_exp,ODE_best_fit(i,:),app.line_colors{i})
                    end
                end

                hold off;

                legend(handles.axes1,app.legend_labels,'Location','northwest')


                %plot(handles.axes1,t_exp,y_exp,'k*',t_exp,ODE_best_fit,'r--');
                set(gca,'FontSize',12);
                xlabel(x_axis,'FontSize',14,'FontWeight','bold');
                ylabel(y_axis,'FontSize',14,'FontWeight','bold');
                

                % edit11 is "kf" textbox under "Fit result"
                % edit12 is "kr" textbox under "Fit result"
                % these textboxes are where the fit rate constants are output
                if mech == 1
                    if fx == 0
                        set(handles.edit11,'String',num2str(bestk(1))); % neither rate constant is fixed
                        set(handles.edit12,'String',num2str(bestk(2)));
                    elseif fx == 1
                        set(handles.edit11,'String','fixed'); % k1 is fixed
                        set(handles.edit12,'String',num2str(bestk(2)));
                    elseif fx == 2
                        set(handles.edit11,'String',num2str(bestk(1)));
                        set(handles.edit12,'String','fixed'); % k2 is fixed
                    end
                elseif mech == 2
                    if fx == 0
                        set(handles.edit11,'String',num2str(bestk(1)));
                        set(handles.edit12,'String',num2str(bestk(2)));
                    elseif fx == 1
                        set(handles.edit11,'String','fixed');
                        set(handles.edit12,'String',num2str(bestk(2)));
                    elseif fx == 2
                        set(handles.edit11,'String',num2str(bestk(1)));
                        set(handles.edit12,'String','fixed');
                    end


                end

                % calculates Kd value by dividing rate constants

                if fx == 0
                    app.k_d = bestk(2)/bestk(1); % divide k2 by k1 to get Kd
                    app.Kd_textbox.Value = num2str(app.k_d);
                elseif fx == 1
                    app.k_d = bestk(2)/k1;
                    app.Kd_textbox.Value = num2str(app.k_d);
                elseif fx == 2
                    app.k_d = k2/bestk(1);
                    app.Kd_textbox.Value = num2str(app.k_d);
                end

                %app.k_d = bestk(2)/bestk(1); % divides k2 by k1 to get Kd
                %app.Kd_textbox.Value = num2str(app.k_d);


                % calculates error in fits as residual standard error, outputs into "error"
                % textbox
                fit_regression = regr(app, y_exp, ODE_best_fit, mech, fx);
                %set(app.edit17, 'String', num2str(fit_regression));
                app.edit17.Value = num2str(fit_regression);

                % handles.data_fit saves the fit data, "Copy result" button copies it to clipboard
                % saves data to handles to use in future parts of the GUI, like export
                % button

                handles.bestk = bestk;
                handles.fit_regression = fit_regression;
                handles.y0 = y0;
                handles.data_fit = ODE_best_fit';
                handles.fx = fx;
                handles.mech = mech;
                handles.k0 = k0;
                handles.low = low;
                handles.up = up;
                app.final_reag_conc = ODE_best_conc;
                app.data_best_fit = ODE_best_fit;


            elseif app.fit_simulate == 2
                % runs this block if "Simulate" is selected
                t_span = app.TimeEditField.Value; % Ending time limit for TIRF simulation; time units are abritrary
                t_sim_interval = app.TimeIntervalEditField.Value; % Interval for simulation time
                tsim = [0:t_sim_interval:t_span]; % makes array for time points from zero to ending simulation time
                t_span = [0 t_span]; % array of start and ending time points

                app.symbols = {'r--','g--','b--','m--','y--','k--'};

                for i = 1:app.ofCurvesEditField.Value
                    app.legend_labels{i} = num2str(i);
                end

                % simulates TIRF binding data using input reagent
                % concentrations, kinetic rate constants, and time limit
                [ODE_sim, ODE_final, ODE_final_conc] = simulate_kinetics(app, k0, t_span, y0, tsim, fx, k1, k2, mech, app.ofCurvesEditField.Value);
                app.ODE_sim = ODE_sim;
                app.tsim = tsim;

                hold on;

                for i = 1:app.ofCurvesEditField.Value
                    if i > size(app.symbols)
                        plot(handles.axes1,tsim,ODE_sim(i,:),app.symbols{1})
                    else
                        plot(handles.axes1,tsim,ODE_sim(i,:),app.symbols{i})
                    end
                end

                %legend(handles.axes1,app.legend_labels,'Location','northwest')

                hold off;

                % sets up plot of simulated data
                x_axis = get(handles.edit18, 'String');
                y_axis = get(handles.edit19, 'String');
                %x_axis = handles.x_axis;
                %y_axis = handles.y_axis;

                %plot(handles.axes1,tsim,ODE_sim,'r--');
                set(gca,'FontSize',12)
                xlabel(x_axis,'FontSize',14,'FontWeight','bold');
                ylabel(y_axis,'FontSize',14,'FontWeight','bold');

                legend(handles.axes1,app.legend_labels,'Location','northwest')

                % saves data to handles/app properties so parameters can be called by
                % other functions in the app

                handles.bestk = k0;
                app.final_simulate_conc = ODE_final;
                app.final_reag_conc = ODE_final_conc;
                app.ODE_sim = ODE_sim;
                app.t_sim = tsim;
                handles.y0 = y0;
                handles.mech = mech;

                % output final reagent concentrations in "Simulate Results"
                % panel
                if mech == 1
                    app.edit11_2.Value = num2str(ODE_final(app.ofCurvesEditField_2.Value,1));
                    app.edit11_3.Value = num2str(ODE_final(app.ofCurvesEditField_2.Value,2));
                elseif mech == 2
                    app.edit11_2.Value = num2str(ODE_final(app.ofCurvesEditField_2.Value,1));
                    app.edit12_2.Value = num2str(ODE_final(app.ofCurvesEditField_2.Value,2));
                    app.edit11_3.Value = num2str(ODE_final(app.ofCurvesEditField_2.Value,3));
                end

                app.simulation_complete = 1;
            end


            guidata(gcbo,handles)
        end

        % Callback function
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton3 is "Copy result" pushbutton, it will copy the fit time courses
            % to the clipboard
            data_fit = handles.data_fit;
            num2clip(data_fit);
            guidata(gcbo,handles)
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton4 is "Clear" pushbutton, it will clear the graph and kf and kr
            % and error textboxes
            cla
            clear all
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pushbutton6 is the "Export" pushbutton located in the results
            % panel

            % this function handles outputting the results into a csv file

            % select for output file type, Fit or Simulation

            if app.fit_simulate == 1

                % input parameters from handles
                data = handles.data; % calibrated raw data
                data_fit = handles.data_fit; % fits to data
                t_exp = handles.t_exp; % time axis data
                y_exp = handles.y_exp; % adsorption (y axis) data
                mech = handles.mech; % mechanism, unimolecular or bimolecular
                fx = handles.fx; % fx selects for fixed rate constant
                fit_regression = handles.fit_regression; % error result

                filename = handles.filename; % filename and pathname are of input file
                pathname = handles.pathname; % used to save output file to same folder
                x_calib = handles.x_calib; % calibration factor for time axis
                y_calib = handles.y_calib; % calibration factor for y axis
                data_exclude = num2str(handles.data_exclude); % column #(s) that were omitted
                k0 = handles.k0; % initial guess rate constants
                low = handles.low; % lower fit bound
                up = handles.up; % upper fit bound
                bestk = handles.bestk; % fit rate constants


                y0 = handles.y0; % starting concentrations for A, B, and P

                x_units = handles.x_axis; % units for calibrated x axis
                y_units = handles.y_axis; % units for calibrated y axis

                y_size = size(data,2);  % # of columns in data, used to make headers in output

                y_units_2 = cell(1, y_size); % array that will be used to hold headers

                x_units_2 = append('Time (',x_units,')'); % makes header for time axis as "Time (unit)"

                for ind = 1:((y_size - 1))
                    y_units_2{ind} = append('P',num2str(ind),' ','(',y_units,')'); % constructs headers for y axis raw data
                end

                for ind = 1:(y_size -1)
                    y_units_2{ind - 1 + y_size} = append('P',num2str(ind),' ','fit',' ','(',y_units,')'); % constructs headres for y axis fit data
                end

                % Gets sizes to construct a cell matrix to allow for data output
                Column_len = size(data, 1)*2; % length of columns in output matrix
                Row_len = ((size(data, 2))*2) + 7; % length of rows in output matrix

                output_matrix = cell(Column_len, Row_len); % making output matrix as a cell

                combined_col = size(data,1);
                combined_row = size(data,2);
                data_combined = zeros([combined_col, combined_row]); % constructs matrix to hold raw and fit data
                data_row = size(data,2);
                fit_data_row = size(data_fit,2);

                data_combined(:,1) = t_exp; % adds calibrated time data to data_combined


                for ind = 1:size(y_exp,2)
                    data_combined(:, (ind + 1)) = y_exp(:,ind); % adds calibrated raw data to data_combined
                end


                for ind = 1:fit_data_row
                    data_combined(:,(ind + size(data,2))) = data_fit(:,ind); % adds fit data to data_combined
                end


                for ind = 1:(size(data_combined,1))
                    for ind_2 = 1:(size(data_combined,2))
                        output_matrix{ind + 1, ind_2} = data_combined(ind, ind_2); % adds data combined to final output matrix
                    end
                end
                % Construct matrix for final reagent concentrations, and
                % add final reagent concentrations from original output matrix
                if mech == 1
                    final_conc_combined = zeros([combined_col, combined_row*2]);
                    int_interval = 2;
                elseif mech == 2
                    final_conc_combined = zeros([combined_col, combined_row*3]);
                    int_interval = 3;
                end
                int = 1;
                for i_3 = 1:size(app.final_reag_conc,3)
                    for i_2 = 1:size(app.final_reag_conc,1)
                        final_conc_combined(:,int) = app.final_reag_conc(i_2,:, i_3)';
                    end
                    int = int + int_interval;
                    
                end

                output_matrix{1,1} = x_units_2; % adds header for time axis data

                for ind = 1:size(y_units_2,2)
                    output_matrix{1, ind + 1} = y_units_2{1, ind}; % adds header for y axis data
                end

                % adds initial reagent & product concentrations and their headers above
                % data columns
                if mech == 1
                    output_matrix{1, (y_size*2)} = append('A0',' ','(',y_units,')');
                    output_matrix{1, (y_size*2 + 1)} = append('P0',' ','(',y_units,')');

                    if size(y0,2) > 1
                        for ind = 1:size(y0,2)
                            output_matrix{1 + ind, (y_size*2)} = y0(1,ind);
                            output_matrix{1 + ind, (y_size*2 + 1)} = y0(2,ind);
                        end
                    else
                        output_matrix{2, (y_size*2)} = y0(1);
                        output_matrix{2, (y_size*2 + 1)} = y0(2);
                    end
                elseif mech == 2
                    output_matrix{1, (y_size*2)} = append('A0',' ','(',y_units,')');
                    output_matrix{1, (y_size*2 + 1)} = append('B0',' ','(',y_units,')');
                    output_matrix{1, (y_size*2 + 2)} = append('P0',' ','(',y_units,')');

                    if size(y0,2) > 1
                        for ind = 1:size(y0,2)
                            output_matrix{1 + ind, (y_size*2)} = y0(1, ind);
                            output_matrix{1 + ind, (y_size*2 + 1)} = y0(2, ind);
                            output_matrix{1 + ind, (y_size*2 + 2)} = y0(3, ind);
                        end
                    else
                        output_matrix{2, (y_size*2)} = y0(1);
                        output_matrix{2, (y_size*2 + 1)} = y0(2);
                        output_matrix{2, (y_size*2 + 2)} = y0(3);
                    end


                end

                % output string for fixed rate constant
                if fx == 0
                    fix_1 = 'no';
                    fix_2 = 'no';
                elseif fx == 1
                    fix_1 = 'yes';
                    fix_2 = 'no';
                elseif fx == 2
                    fix_1 = 'no';
                    fix_2 = 'yes';
                end

                % output string for reaction fit mechanism
                if mech == 1
                    mechanism = 'unimolecular';
                elseif mech == 2
                    mechanism = 'bimolecular';
                end
                % output for input parameters, first line is label, second is values
                if mech == 1
                    parameter_input_labels = {'Mechanism','x calibration factor', 'y calibration factor', 'lower k bound', 'upper k bound', 'guess k1', 'guess k2', 'Exclude Column #', 'fit k1', 'fit k2','Kd', 'fit error', 'k1 fixed?', 'k2 fixed?'};
                    parameter_inputs = {mechanism, x_calib, y_calib, low , up, k0(1), k0(2),data_exclude,bestk(1),bestk(2),app.k_d,fit_regression,fix_1,fix_2};
                elseif mech == 2
                    parameter_input_labels = {'Mechanism','x calibration factor', 'y calibration factor', 'lower k bound', 'upper k bound', 'guess k1', 'guess k2', 'Exclude Column #', 'fit k1', 'fit k2','Kd', 'fit error', 'k1 fixed?', 'k2 fixed?'};
                    parameter_inputs = {mechanism, x_calib, y_calib, low , up, k0(1), k0(2),data_exclude,bestk(1),bestk(2),app.k_d,fit_regression,fix_1,fix_2};
                end
                %parameter_input_labels = {'Mechanism','x calibration factor', 'y calibration factor', 'lower k bound', 'upper k bound', 'guess kf', 'guess kr', 'Exclude Column #', 'fit kf', 'fit kr', 'fit error', 'kr fixed?', 'kf fixed?'};
                %parameter_inputs = {mechanism, x_calib, y_calib, low , up, k0(1), k0(2),data_exclude,bestk(1),bestk(2),fit_regression,fix_1,fix_2};


                output_size = size(output_matrix,2); % gets size to use to place input parameters

                if mech == 1
                    output_y_index = y_size*2 + 2;
                elseif mech == 2
                    output_y_index = y_size*2 + 3;
                end


                for ind = 1:size(parameter_input_labels,2)
                    output_matrix{ind, (output_y_index)} = parameter_input_labels{ind}; % places labels for input parameters
                    output_matrix{ind, (output_y_index + 1)} = parameter_inputs{ind}; % places input parameters
                end

                app.final_reag_conc = pagetranspose(app.final_reag_conc);

                y_marker_2 = 0;

                output_y_index = output_y_index + 1;

                for ind_3 = 1:size(app.final_reag_conc,3)
                    for ind_2 = 1:size(app.final_reag_conc,2)
                        for ind_1 = 1:size(app.final_reag_conc,1)
                            output_matrix{ind_1 + 1, output_y_index + ind_2 + y_marker_2} = app.final_reag_conc(ind_1,ind_2,ind_3);
                        end
                        
                    end
                    y_marker_2 = y_marker_2 + size(app.final_reag_conc,2);
                end

                
                y_marker_2 = 0;

                ind_1 = 1;

                output_y_index = output_y_index + 1;

                if mech == 1
                    for ind_3 = 1:size(app.final_reag_conc,3)
                        output_matrix{1, output_y_index + y_marker_2} = append('A',num2str(ind_1),' ',y_units);
                        output_matrix{1, output_y_index + y_marker_2 + 1} = append('P',num2str(ind_1),' ',y_units);
                        y_marker_2 = y_marker_2 + size(app.final_reag_conc,2);
                        ind_1 = ind_1 + 1;
                    end
                    
                elseif mech == 2
                    for ind_3 = 1:size(app.final_reag_conc,3)
                        output_matrix{1, output_y_index + y_marker_2} = append('A',num2str(ind_1),' ',y_units);
                        output_matrix{1, output_y_index + y_marker_2 + 1} = append('B',num2str(ind_1),' ',y_units);
                        output_matrix{1, output_y_index + y_marker_2 + 2} = append('P',num2str(ind_1),' ',y_units);
                        y_marker_2 = y_marker_2 + size(app.final_reag_conc,2);
                        ind_1 = ind_1 + 1;
                    end
                end

                app.final_reag_conc = pagetranspose(app.final_reag_conc);


                % places units for input parameters (where necessary)
                %output_matrix{11, y_size*2 + 5} = y_units;

                % changes cell to a table for final output
                %output = cell2table(output_matrix(2:end,:), 'VariableNames',output_matrix(1,:));
                output = cell2table(output_matrix);

                app.output = output;

                filename = erase(filename, '.txt'); % erases ".txt" from input file name
                new_file_name = append(pathname,filename,'_',app.AppendNameEditField.Value,'_report.csv'); % adds "Append Name" string and "_report.csv" to new file name

                writetable(output, new_file_name); % makes output file

            elseif app.fit_simulate == 2

                % block runs similarly to the above block for "Fit"
                % setting, only with the simulated data

                y0 = handles.y0; % starting concentrations for A, B, and P

                x_units = get(handles.edit18, 'String'); % units for calibrated x axis
                y_units = get(handles.edit19, 'String'); % unites for calibrated y axis
                t_sim_T = app.t_sim';
                final_reag_conc_T = pagetranspose(app.final_reag_conc);
                ODE_sim_t = app.ODE_sim';
                mech = app.mechanism;
                k0 = handles.bestk;

                size_1 = size(t_sim_T,1);

                size_2 = size(ODE_sim_t,2)*8;

                output_matrix = cell(size_1, size_2);

                y_size = size(ODE_sim_t,2);

                y_units_2 = cell(1,y_size);

                x_units_2 = append('Time',' ','(',x_units,')');


                for ind = 1:(y_size)
                    y_units_2{ind} = append('P',num2str(ind),' ','(',y_units,')'); % constructs headers for y axis raw data
                end

                output_matrix{1,1} = x_units_2;

                for i = 1:size(y_units_2,2)
                    output_matrix{1,i + 1} = y_units_2{i};
                end

                
                for i = 1:size(t_sim_T)
                    output_matrix{i + 1,1} = t_sim_T(i);
                end

                for i_1 = 1:size(ODE_sim_t,1)
                    for i_2 = 1:size(ODE_sim_t,2)
                        output_matrix{i_1 + 1,i_2 + 1} = ODE_sim_t(i_1,i_2);
                    end
                end

                if mech == 1
                    output_matrix{1, (y_size + 2)} = append('A0',' ','(',y_units,')');
                    output_matrix{1, (y_size + 3)} = append('P0',' ','(',y_units,')');

                    if y_size > 1
                        for ind = 1:size(y0,2)
                            output_matrix{1 + ind, (y_size + 2)} = y0(1,ind);
                            output_matrix{1 + ind, (y_size + 3)} = y0(2,ind);
                        end
                    else
                        output_matrix{2, (y_size + 2)} = y0(1);
                        output_matrix{2, (y_size + 3)} = y0(2);
                    end
                    y_marker = y_size + 4;
                elseif mech == 2
                    output_matrix{1, (y_size + 2)} = append('A0',' ','(',y_units,')');
                    output_matrix{1, (y_size + 3)} = append('B0',' ','(',y_units,')');
                    output_matrix{1, (y_size + 4)} = append('P0',' ','(',y_units,')');

                    if y_size > 1
                        for ind = 1:size(y0,2)
                            output_matrix{1 + ind, (y_size + 2)} = y0(1, ind);
                            output_matrix{1 + ind, (y_size + 3)} = y0(2, ind);
                            output_matrix{1 + ind, (y_size + 4)} = y0(3, ind);
                        end
                    else
                        output_matrix{2, (y_size + 2)} = y0(1);
                        output_matrix{2, (y_size + 3)} = y0(2);
                        output_matrix{2, (y_size + 4)} = y0(3);
                    end
                    y_marker = y_size + 5;

                end

                if mech == 1
                    mechanism = 'unimolecular';
                elseif mech == 2
                    mechanism = 'bimolecular';
                end


                %parameter_input_labels = cell(11,2);

                %parameter_input_labels{:,1} = {'Mechanism', 'X Units', 'Y Units', 'Simulation Time', '# of Curves', 'k1', 'k2', 'k3', 'k4', 'k5', 'k6'};
                
                
                parameter_input_labels = {'Mechanism', 'X Units', 'Y Units', 'Simulation Time', '# of Curves', 'k1', 'k2','Kd'};
                parameter_input = {mechanism, x_units, y_units, app.TimeEditField.Value, app.ofCurvesEditField.Value, k0(1), k0(2),app.k_d};
                
                for i_1 = 1:8
                    output_matrix{i_1,y_marker} = parameter_input_labels{i_1};
                    output_matrix{i_1,y_marker + 1} = parameter_input{i_1};
                end
                

                y_marker = y_marker + 2;

                output_matrix{1,y_marker} = x_units_2;

                for i = 1:size(t_sim_T)
                    output_matrix{i + 1, y_marker} = t_sim_T(i);
                end

                y_marker = y_marker + 1;
                y_marker_2 = y_marker;
                ind_1 = 1;

                if mech == 1
                    for ind_3 = 1:size(final_reag_conc_T,3)
                        output_matrix{1, y_marker_2} = append('A',num2str(ind_1),' ',y_units);
                        output_matrix{1, y_marker_2 + 1} = append('P',num2str(ind_1),' ',y_units);
                        y_marker_2 = y_marker_2 + size(final_reag_conc_T,2);
                        ind_1 = ind_1 + 1;
                    end

                elseif mech == 2
                    for ind_3 = 1:size(final_reag_conc_T,3)
                        output_matrix{1, y_marker_2} = append('A',num2str(ind_1),' ',y_units);
                        output_matrix{1, y_marker_2 + 1} = append('B',num2str(ind_1),' ',y_units);
                        output_matrix{1, y_marker_2 + 2} = append('P',num2str(ind_1),' ',y_units);
                        y_marker_2 = y_marker_2 + size(final_reag_conc_T,2);
                        ind_1 = ind_1 + 1;
                    end
                end

                y_marker = y_marker - 1;

                for i_3 = 1:size(final_reag_conc_T,3)
                    for i_2 = 1:size(final_reag_conc_T,2)
                        for i_1 = 1:size(final_reag_conc_T,1)
                            output_matrix{i_1 + 1, i_2 + y_marker} = final_reag_conc_T(i_1,i_2,i_3);
                        end
                    end
                    y_marker = y_marker + size(final_reag_conc_T,2);
                end

                output = cell2table(output_matrix);

                app.output = output;

                filename = app.AppendNameEditField.Value;
                new_file_name = append(filename,'_report.csv'); % adds "_report.csv" to new file name

                writetable(output, new_file_name); % makes output file

            end

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if strcmp(value, 'Fit')
                app.fit_simulate = 1;
            elseif strcmp(value, 'Simulate')
                app.fit_simulate = 2;

            end


            app.Switch.Value = value; % value can either be 'Fit' or 'Simulate'
        end

        % Callback function
        function edit4_2ValueChanged(app, event)
            value = app.edit4_2.Value;
            % returns value for D0 concentration
        end

        % Callback function
        function edit4_3ValueChanged(app, event)
            value = app.edit4_3.Value;
            % returns value for E0 concentration
        end

        % Callback function
        function edit8_2ValueChanged(app, event)
            value = app.edit8_2.Value;
            % k3 value
        end

        % Callback function
        function edit9_2ValueChanged(app, event)
            value = app.edit9_2.Value;
            % k4 value
        end

        % Callback function
        function edit8_3ValueChanged(app, event)
            value = app.edit8_3.Value;
            % k5 value
        end

        % Callback function
        function edit9_3ValueChanged(app, event)
            value = app.edit9_3.Value;
            % k6 value
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.AppendNameEditField.Value;
            % Textbox for fit k3 value
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            % Textbox for fit k4 value
        end

        % Callback function
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            % Textbox for fit k5 value
        end

        % Callback function
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            % Textbox for fit k6 value
        end

        % Callback function
        function A02EditFieldValueChanged(app, event)
            value = app.A02EditField.Value;
            % new textbox for A0 values
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            app.TimeEditField.Value = value;
        end

        % Value changed function: ofCurvesEditField
        function ofCurvesEditFieldValueChanged(app, event)
            value = app.ofCurvesEditField.Value; % # of curves to simulate
            app.ofCurvesEditField.Value = value;

        end

        % Value changed function: edit11_2
        function edit11_2ValueChanged(app, event)
            value = app.edit11_2.Value;
            % output value for final [A] simulated
        end

        % Value changed function: edit12_2
        function edit12_2ValueChanged(app, event)
            value = app.edit12_2.Value;
            % output value for final [B] simulated
        end

        % Value changed function: edit11_3
        function edit11_3ValueChanged(app, event)
            value = app.edit11_3.Value;
            % output value for final [C] simulated

        end

        % Callback function
        function edit12_3ValueChanged(app, event)
            value = app.edit12_3.Value;
            % output value for final [D] simulated
        end

        % Callback function
        function edit12_4ValueChanged(app, event)
            value = app.edit12_4.Value;
            % output value for final [E] simulated
        end

        % Callback function
        function edit11_4ValueChanged(app, event)
            value = app.edit11_4.Value;
            % output value for fit k3
        end

        % Callback function
        function edit11_5ValueChanged(app, event)
            value = app.edit11_5.Value;
            % output value for fit k4
        end

        % Callback function
        function edit11_6ValueChanged(app, event)
            value = app.edit11_6.Value;
            % output value for fit k5
        end

        % Callback function
        function edit11_7ValueChanged(app, event)
            value = app.edit11_7.Value;
            % output value for fit k6
        end

        % Callback function
        function ofCurvesEditField_2ValueChanged(app, event)


        end

        % Callback function
        function edit12_5ValueChanged(app, event)
            value = app.edit12_5.Value;
            app.edit12_5.Value = str2num(value);
            % Show final concentration of this curve number
        end

        % Value changed function: ofCurvesEditField_2
        function ofCurvesEditField_2ValueChanged2(app, event)
            % Selects which curve to show the final reagent concentrations
            % for simulated curves
            value = app.ofCurvesEditField_2.Value;
            app.ofCurvesEditField_2.Value = value;
            mech = app.mechanism;
            if app.simulation_complete == 1
                if app.ofCurvesEditField_2.Value <= app.ofCurvesEditField.Value
                    if mech == 1
                        app.edit11_2.Value = num2str(app.final_simulate_conc(app.ofCurvesEditField_2.Value,1));
                        app.edit11_3.Value = num2str(app.final_simulate_conc(app.ofCurvesEditField_2.Value,2));
                    elseif mech == 2
                        app.edit11_2.Value = num2str(app.final_simulate_conc(app.ofCurvesEditField_2.Value,1));
                        app.edit12_2.Value = num2str(app.final_simulate_conc(app.ofCurvesEditField_2.Value,2));
                        app.edit11_3.Value = num2str(app.final_simulate_conc(app.ofCurvesEditField_2.Value,3));
                    end
                end
            end


        end

        % Callback function
        function edit17_2ValueChanged(app, event)
            value = app.Kd_textbox.Value; % error textbox

        end

        % Callback function
        function checkbox1_2ValueChanged(app, event)
            value = app.checkbox1_2.Value; % fix k3
            app.checkbox1_2.Value = value;
        end

        % Callback function
        function checkbox2_2ValueChanged(app, event)
            value = app.checkbox2_2.Value; % fix k4
            app.checkbox2_2.Value = value;

        end

        % Callback function
        function checkbox1_3ValueChanged(app, event)
            value = app.checkbox1_3.Value; % fix k5
            app.checkbox1_3.Value = value;
        end

        % Callback function
        function checkbox2_3ValueChanged(app, event)
            value = app.checkbox2_3.Value; % fix k6
            app.checkbox2_3.Value = value;

        end

        % Value changed function: AppendNameEditField
        function AppendNameEditFieldValueChanged(app, event)
            value = app.AppendNameEditField.Value; % field to add text to exported file name
            app.AppendNameEditField.Value = value;
        end

        % Callback function
        function AppendNameCheckBoxValueChanged(app, event)
            value = app.AppendNameCheckBox.Value; % 
            app.AppendNameCheckBox.Value = value;
        end

        % Value changed function: Kd_textbox
        function Kd_textboxValueChanged(app, event)
            value = app.Kd_textbox.Value;
            
        end

        % Value changed function: TIRFFCSSlopeTIRFum2EditField
        function TIRFFCSSlopeTIRFum2EditFieldValueChanged(app, event)
            value = app.TIRFFCSSlopeTIRFum2EditField.Value;
            app.TIRFFCSSlopeTIRFum2EditField.Value = value;

            % change the output Y-calibration factor when this textbox is
            % updated, assumed units are in fluorescence/molecules/um^2
            % divide reciprocal slope by Avogadro's number to get moles/um^2
            SLB_SA = app.SLBSurfaceAreamm2EditField.Value*(1E6);

            N_a = 6.022e23;
            y_cal_factor = (1/app.TIRFFCSSlopeTIRFum2EditField.Value)*(1/N_a)*(SLB_SA)*(1E12);
            app.YCalFactorpmolTIRFEditField.Value = y_cal_factor;
            app.edit10.Value = num2str(y_cal_factor);

        end

        % Value changed function: SLBSurfaceAreamm2EditField
        function SLBSurfaceAreamm2EditFieldValueChanged(app, event)
            value = app.SLBSurfaceAreamm2EditField.Value;
            app.SLBSurfaceAreamm2EditField.Value = value;

            % change the output y_calibration factor when this textbox is
            % updated
            FCS_TIRF_slope = app.TIRFFCSSlopeTIRFum2EditField.Value;
            SLB_SA = app.SLBSurfaceAreamm2EditField.Value*(1E6);
            N_a = 6.022E23;
            y_cal_factor = (1/FCS_TIRF_slope)*(1/N_a)*(SLB_SA)*(1E12);
            app.YCalFactorpmolTIRFEditField.Value = y_cal_factor;
            app.edit10.Value = num2str(y_cal_factor);

            % block to change [B] from mol% to pmol

            Lipids_per_squm = (1/0.72)*(1E6); % number of phospholipid headgroups in 1 um^2
            pmol_lipid_B = (app.BmolEditField.Value/100)*(Lipids_per_squm)*(1/N_a)*(1E12)*SLB_SA;
            app.BpmolEditField.Value = pmol_lipid_B;


        end

        % Value changed function: YCalFactorpmolTIRFEditField
        function YCalFactorpmolTIRFEditFieldValueChanged(app, event)
            value = app.YCalFactorpmolTIRFEditField.Value;
            
        end

        % Value changed function: AnMproteinEditField
        function AnMproteinEditFieldValueChanged(app, event)
            value = app.AnMproteinEditField.Value;
            app.AnMproteinEditField.Value = value;
            % add function to take in concentration of protein A, in nM,
            % and convert it to pmols given input volume in uL

            Sample_volume = app.VuLofAEditField.Value*(1/(1E6)); % convert volume in uL to L
            Protein_A_in_M = app.AnMproteinEditField.Value*(1/(1E9)); % convert [A] from nM to M

            protein_pmol = Sample_volume*Protein_A_in_M*(1E12);
            app.ApmolEditField.Value = protein_pmol;

            
        end

        % Value changed function: VuLofAEditField
        function VuLofAEditFieldValueChanged(app, event)
            value = app.VuLofAEditField.Value;
            app.VuLofAEditField.Value = value;

            Sample_volume = app.VuLofAEditField.Value*(1/(1E6));
            Protein_A_in_M = app.AnMproteinEditField.Value*(1/(1E9));

            protein_pmol = Sample_volume*Protein_A_in_M*(1E12);
            app.ApmolEditField.Value = protein_pmol;


        end

        % Value changed function: ApmolEditField
        function ApmolEditFieldValueChanged(app, event)
            value = app.ApmolEditField.Value;
            
        end

        % Value changed function: BmolEditField
        function BmolEditFieldValueChanged(app, event)
            value = app.BmolEditField.Value;
            app.BmolEditField.Value = value;
            % Change B, the mol percentage of lipid species into pmol
            N_a = 6.022E23;
            SLB_SA = app.SLBSurfaceAreamm2EditField.Value*(1E6); % sample area of SLB
            Lipids_per_squm = (1/0.72)*(1E6); % number of phospholipid headgroups in 1 um^2
            pmol_lipid_B = (app.BmolEditField.Value/100)*(Lipids_per_squm)*(1/N_a)*(1E12)*SLB_SA;
            app.BpmolEditField.Value = pmol_lipid_B;
            
        end

        % Value changed function: BpmolEditField
        function BpmolEditFieldValueChanged(app, event)
            value = app.BpmolEditField.Value;
            
        end

        % Value changed function: TimeIntervalEditField
        function TimeIntervalEditFieldValueChanged(app, event)
            value = app.TimeIntervalEditField.Value;
            app.TimeIntervalEditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.Tag = 'figure1';
            app.DataVisualizationPanel = uipanel(app.figure1);
            app.DataVisualizationPanel.Title = 'Data Visualization Panel';
            app.axes1 = uiaxes(app.DataVisualizationPanel);
            app.axes1.Tag = 'axes1';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Reaction Conditions Panel';
            app.uipanel7.Tag = 'uipanel7';
            app.edit2 = uieditfield(app.uipanel7, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '0.5,1,2,4';
            app.edit3 = uieditfield(app.uipanel7, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '5.26';
            app.text4 = uilabel(app.uipanel7);
            app.text4.Tag = 'text4';
            app.text4.Text = 'A0';
            app.text5 = uilabel(app.uipanel7);
            app.text5.Tag = 'text5';
            app.text5.Text = 'B0';
            app.text6 = uilabel(app.uipanel7);
            app.text6.Tag = 'text6';
            app.text6.Text = 'P0';
            app.edit8 = uieditfield(app.uipanel7, 'text');
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Tag = 'edit8';
            app.edit8.Value = '8E-4';
            app.edit9 = uieditfield(app.uipanel7, 'text');
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9_Callback, true);
            app.edit9.Tag = 'edit9';
            app.edit9.Value = '3E-3';
            app.text7 = uilabel(app.uipanel7);
            app.text7.Tag = 'text7';
            app.text7.Text = 'k1';
            app.text8 = uilabel(app.uipanel7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'k-1';
            app.checkbox1 = uicheckbox(app.uipanel7);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'fix';
            app.checkbox2 = uicheckbox(app.uipanel7);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'fix';
            app.edit4 = uieditfield(app.uipanel7, 'text');
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '0';
            app.Label = uilabel(app.uipanel7);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.uipanel7);
            app.Label_2.Text = '-->';
            app.Label_3 = uilabel(app.uipanel7);
            app.Label_3.Text = '<--';
            app.text24 = uilabel(app.uipanel7);
            app.text24.Tag = 'text24';
            app.text24.Text = 'separate [A0] and [B0] with commas; arrange in same order as data file';
            app.popupmenu1 = uidropdown(app.uipanel7);
            app.popupmenu1.Items = {'Unimolecular', 'Bimolecular'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Unimolecular';
            app.text9_2 = uilabel(app.uipanel7);
            app.text9_2.Tag = 'text9';
            app.text9_2.Text = 'A <--> P';
            app.Switch = uiswitch(app.uipanel7, 'toggle');
            app.Switch.Items = {'Simulate', 'Fit'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Fit';
            app.pushbutton2 = uibutton(app.uipanel7, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = {'Fit/Sim'; ''};
            app.text19 = uilabel(app.uipanel7);
            app.text19.Tag = 'text19';
            app.text19.Text = 'fit k bounds';
            app.text20 = uilabel(app.uipanel7);
            app.text20.Tag = 'text20';
            app.text20.Text = 'low';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Tag = 'text21';
            app.text21.Text = 'up';
            app.edit16 = uieditfield(app.uipanel7, 'text');
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit16_Callback, true);
            app.edit16.Tag = 'edit16';
            app.edit16.Value = '10';
            app.edit15 = uieditfield(app.uipanel7, 'text');
            app.edit15.ValueChangedFcn = createCallbackFcn(app, @edit15_Callback, true);
            app.edit15.Tag = 'edit15';
            app.edit15.Value = '0';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Data Loading Panel';
            app.uipanel2.Tag = 'uipanel2';
            app.edit1 = uieditfield(app.uipanel2, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '1';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'x:';
            app.edit10 = uieditfield(app.uipanel2, 'text');
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit10_Callback, true);
            app.edit10.Tag = 'edit10';
            app.edit10.Value = '1.41E-5';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'y:';
            app.edit18 = uieditfield(app.uipanel2, 'text');
            app.edit18.ValueChangedFcn = createCallbackFcn(app, @edit18_Callback, true);
            app.edit18.Tag = 'edit18';
            app.edit18.Value = 's';
            app.edit19 = uieditfield(app.uipanel2, 'text');
            app.edit19.ValueChangedFcn = createCallbackFcn(app, @edit19_Callback, true);
            app.edit19.Tag = 'edit19';
            app.edit19.Value = 'pmol';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Exclude Column #';
            app.edit13 = uieditfield(app.uipanel2, 'text');
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit13_Callback, true);
            app.edit13.Tag = 'edit13';
            app.edit13.Value = '0';
            app.ExtraSimulationParametersPanel = uipanel(app.uipanel2);
            app.ExtraSimulationParametersPanel.Title = 'Extra Simulation Parameters';
            app.TimeEditFieldLabel = uilabel(app.ExtraSimulationParametersPanel);
            app.TimeEditFieldLabel.Text = 'Time ';
            app.TimeEditField = uieditfield(app.ExtraSimulationParametersPanel, 'numeric');
            app.TimeEditField.Limits = [0 Inf];
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 600;
            app.ofCurvesEditFieldLabel = uilabel(app.ExtraSimulationParametersPanel);
            app.ofCurvesEditFieldLabel.Text = '# of Curves';
            app.ofCurvesEditField = uieditfield(app.ExtraSimulationParametersPanel, 'numeric');
            app.ofCurvesEditField.Limits = [1 Inf];
            app.ofCurvesEditField.ValueChangedFcn = createCallbackFcn(app, @ofCurvesEditFieldValueChanged, true);
            app.ofCurvesEditField.Value = 4;
            app.TimeIntervalEditFieldLabel = uilabel(app.ExtraSimulationParametersPanel);
            app.TimeIntervalEditFieldLabel.Text = 'Time Interval';
            app.TimeIntervalEditField = uieditfield(app.ExtraSimulationParametersPanel, 'numeric');
            app.TimeIntervalEditField.Limits = [0 Inf];
            app.TimeIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalEditFieldValueChanged, true);
            app.TimeIntervalEditField.Value = 1;
            app.pushbutton1 = uibutton(app.uipanel2, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Load data';
            app.pushbutton4 = uibutton(app.uipanel2, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Clear';
            app.CalculationsPanel = uipanel(app.uipanel2);
            app.CalculationsPanel.Title = 'Calculations Panel';
            app.TIRFFCSSlopeTIRFum2EditFieldLabel = uilabel(app.CalculationsPanel);
            app.TIRFFCSSlopeTIRFum2EditFieldLabel.Text = 'TIRF-FCS Slope (TIRF/#/um^2)';
            app.TIRFFCSSlopeTIRFum2EditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.TIRFFCSSlopeTIRFum2EditField.Limits = [0 Inf];
            app.TIRFFCSSlopeTIRFum2EditField.ValueDisplayFormat = '%11.5g';
            app.TIRFFCSSlopeTIRFum2EditField.ValueChangedFcn = createCallbackFcn(app, @TIRFFCSSlopeTIRFum2EditFieldValueChanged, true);
            app.TIRFFCSSlopeTIRFum2EditField.Value = 6.6794;
            app.YCalFactorpmolTIRFEditFieldLabel = uilabel(app.CalculationsPanel);
            app.YCalFactorpmolTIRFEditFieldLabel.Text = 'Y-Cal Factor (pmol/TIRF)';
            app.YCalFactorpmolTIRFEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.YCalFactorpmolTIRFEditField.ValueChangedFcn = createCallbackFcn(app, @YCalFactorpmolTIRFEditFieldValueChanged, true);
            app.YCalFactorpmolTIRFEditField.Editable = 'off';
            app.YCalFactorpmolTIRFEditField.Value = 1.418e-05;
            app.AnMproteinEditFieldLabel = uilabel(app.CalculationsPanel);
            app.AnMproteinEditFieldLabel.Text = '[A] (nM) (protein) ';
            app.AnMproteinEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.AnMproteinEditField.Limits = [0 Inf];
            app.AnMproteinEditField.ValueChangedFcn = createCallbackFcn(app, @AnMproteinEditFieldValueChanged, true);
            app.AnMproteinEditField.Value = 100;
            app.ApmolEditFieldLabel = uilabel(app.CalculationsPanel);
            app.ApmolEditFieldLabel.Text = '[A] (pmol)';
            app.ApmolEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.ApmolEditField.ValueChangedFcn = createCallbackFcn(app, @ApmolEditFieldValueChanged, true);
            app.ApmolEditField.Editable = 'off';
            app.BmolEditFieldLabel = uilabel(app.CalculationsPanel);
            app.BmolEditFieldLabel.Text = '[B] (mol%)';
            app.BmolEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.BmolEditField.Limits = [0 Inf];
            app.BmolEditField.ValueChangedFcn = createCallbackFcn(app, @BmolEditFieldValueChanged, true);
            app.BmolEditField.Value = 1;
            app.BpmolEditFieldLabel = uilabel(app.CalculationsPanel);
            app.BpmolEditFieldLabel.Text = '[B] (pmol)';
            app.BpmolEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.BpmolEditField.Limits = [0 Inf];
            app.BpmolEditField.ValueChangedFcn = createCallbackFcn(app, @BpmolEditFieldValueChanged, true);
            app.BpmolEditField.Editable = 'off';
            app.BpmolEditField.Value = 1.31;
            app.VuLofAEditFieldLabel = uilabel(app.CalculationsPanel);
            app.VuLofAEditFieldLabel.Text = 'V (uL) of A';
            app.VuLofAEditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.VuLofAEditField.Limits = [0 Inf];
            app.VuLofAEditField.ValueChangedFcn = createCallbackFcn(app, @VuLofAEditFieldValueChanged, true);
            app.VuLofAEditField.Value = 100;
            app.SLBSurfaceAreamm2EditFieldLabel = uilabel(app.CalculationsPanel);
            app.SLBSurfaceAreamm2EditFieldLabel.Text = 'SLB Surface Area (mm^2)';
            app.SLBSurfaceAreamm2EditField = uieditfield(app.CalculationsPanel, 'numeric');
            app.SLBSurfaceAreamm2EditField.Limits = [0 Inf];
            app.SLBSurfaceAreamm2EditField.ValueChangedFcn = createCallbackFcn(app, @SLBSurfaceAreamm2EditFieldValueChanged, true);
            app.SLBSurfaceAreamm2EditField.Value = 57;
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = '[time P1 P2 ... Pn]';
            app.ResultsPanel = uipanel(app.figure1);
            app.ResultsPanel.Title = 'Results Panel';
            app.uipanel3 = uipanel(app.ResultsPanel);
            app.uipanel3.Title = 'Fit Results';
            app.uipanel3.Tag = 'uipanel3';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'k1';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Text = 'k-1';
            app.edit11 = uieditfield(app.uipanel3, 'text');
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11_Callback, true);
            app.edit11.Tag = 'edit11';
            app.edit11.Editable = 'off';
            app.edit12 = uieditfield(app.uipanel3, 'text');
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit12_Callback, true);
            app.edit12.Tag = 'edit12';
            app.edit12.Editable = 'off';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tag = 'text22';
            app.text22.Text = 'error';
            app.edit17 = uieditfield(app.uipanel3, 'text');
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit17_Callback, true);
            app.edit17.Tag = 'edit17';
            app.edit17.Editable = 'off';
            app.text22_2 = uilabel(app.uipanel3);
            app.text22_2.Tag = 'text22';
            app.text22_2.Text = 'Kd';
            app.Kd_textbox = uieditfield(app.uipanel3, 'text');
            app.Kd_textbox.ValueChangedFcn = createCallbackFcn(app, @Kd_textboxValueChanged, true);
            app.Kd_textbox.Tag = 'edit17';
            app.Kd_textbox.Editable = 'off';
            app.uipanel3_2 = uipanel(app.ResultsPanel);
            app.uipanel3_2.Title = 'Simulate Results';
            app.uipanel3_2.Tag = 'uipanel3';
            app.text15_4 = uilabel(app.uipanel3_2);
            app.text15_4.Tag = 'text15';
            app.text15_4.Text = 'Af';
            app.text16_4 = uilabel(app.uipanel3_2);
            app.text16_4.Tag = 'text16';
            app.text16_4.Text = 'Bf';
            app.edit11_2 = uieditfield(app.uipanel3_2, 'text');
            app.edit11_2.ValueChangedFcn = createCallbackFcn(app, @edit11_2ValueChanged, true);
            app.edit11_2.Tag = 'edit11';
            app.edit11_2.Editable = 'off';
            app.edit12_2 = uieditfield(app.uipanel3_2, 'text');
            app.edit12_2.ValueChangedFcn = createCallbackFcn(app, @edit12_2ValueChanged, true);
            app.edit12_2.Tag = 'edit12';
            app.edit12_2.Editable = 'off';
            app.text15_5 = uilabel(app.uipanel3_2);
            app.text15_5.Tag = 'text15';
            app.text15_5.Text = 'Pf';
            app.edit11_3 = uieditfield(app.uipanel3_2, 'text');
            app.edit11_3.ValueChangedFcn = createCallbackFcn(app, @edit11_3ValueChanged, true);
            app.edit11_3.Tag = 'edit11';
            app.edit11_3.Editable = 'off';
            app.ofCurvesEditFieldLabel_2 = uilabel(app.uipanel3_2);
            app.ofCurvesEditFieldLabel_2.Text = 'For Curve #';
            app.ofCurvesEditField_2 = uieditfield(app.uipanel3_2, 'numeric');
            app.ofCurvesEditField_2.Limits = [1 Inf];
            app.ofCurvesEditField_2.ValueChangedFcn = createCallbackFcn(app, @ofCurvesEditField_2ValueChanged2, true);
            app.ofCurvesEditField_2.Value = 1;
            app.pushbutton6 = uibutton(app.ResultsPanel, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'Export';
            app.AppendNameEditFieldLabel = uilabel(app.ResultsPanel);
            app.AppendNameEditFieldLabel.Text = 'Append Name';
            app.AppendNameEditField = uieditfield(app.ResultsPanel, 'text');
            app.AppendNameEditField.ValueChangedFcn = createCallbackFcn(app, @AppendNameEditFieldValueChanged, true);
            app.AppendNameEditField.Value = 'trial_1';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'MARK - Membrane Adsorption and Recruitment Kinetics';
            app.ContextMenu = uicontextmenu(app.figure1);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.popupmenu1.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MARK(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end